classdef doExtractRectangles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        PanButton                     matlab.ui.control.Button
        ZoomButton                    matlab.ui.control.Button
        ExcludePositionsListBoxLabel  matlab.ui.control.Label
        ExcludePos                    matlab.ui.control.ListBox
        NavigationPanel               matlab.ui.container.Panel
        XYPosSpinnerLabel             matlab.ui.control.Label
        XYPosSpinner                  matlab.ui.control.Spinner
        ZPosSpinnerLabel              matlab.ui.control.Label
        ZPosSpinner                   matlab.ui.control.Spinner
        TimeSpinnerLabel              matlab.ui.control.Label
        TimeSpinner                   matlab.ui.control.Spinner
        ChannelSpinnerLabel           matlab.ui.control.Label
        ChannelSpinner                matlab.ui.control.Spinner
        HandleRectanglesPanel         matlab.ui.container.Panel
        AddRectangleButton            matlab.ui.control.Button
        DeleteSelectedButton          matlab.ui.control.Button
        SelectrectangleListBoxLabel   matlab.ui.control.Label
        SelectRect                    matlab.ui.control.ListBox
        CONFIRMButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        reader          % bfreader object
        imgHandle       % Handle to image displayed on axis
        rects           % Drawn rectangles 
        posLookup       % Position Lookup
        
        % Global variables for callback
        rectTopLeft
        rectBottomRight
        
        % Calling app
        callingApp 
        
    end
    
    methods (Access = private)
        % -----------------------------------------------------
        % INITIALIZATION FUNCTIONS
        % Related to startup / initialization
        % -----------------------------------------------------
        
        function [] = initializeSpinnerBounds(app)
            % Inizializes Spinners to have upper limit (or deactivate
            % if the upper limit is equal to the lower limit)
            sizeXY = app.reader.getSeriesCount;
            sizeZ = app.reader.getSizeZ;
            sizeT = app.reader.getSizeT;
            sizeC = app.reader.getSizeC;
            
            function setUpperLimit(spinner, size)
                if size==1
                    spinner.Enable = 0;
                else
                    spinner.Limits = [1 size];
                end
            end
            
            setUpperLimit(app.ZPosSpinner, sizeZ)
            setUpperLimit(app.XYPosSpinner, sizeXY)
            setUpperLimit(app.TimeSpinner, sizeT)
            setUpperLimit(app.ChannelSpinner, sizeC)
        end
    
        function [] = initializeLists(app)
            % Initializes the item lists
            app.SelectRect.Items = {};
            
            sizeXY = app.reader.getSeriesCount;
            items = cell(sizeXY,1);
            for i = 1:sizeXY
                items{i} = num2str(i);
            end
            app.ExcludePos.Items = items;
            app.ExcludePos.Multiselect = 'on';
            app.ExcludePos.Value = {};
        end
    
        % -----------------------------------------------------
        % DISPLAY FUNCTIONS
        % Related to image display
        % -----------------------------------------------------
        function [] = displayImg(app)
            img = getFrame(app);
            img8bit = uint8(rescale(img, 0, 255));
            rgb = cat(3, img8bit, img8bit, img8bit);
            app.imgHandle = imshow(rgb, 'Parent', app.UIAxes);
            displayRects(app)
        end
    
        function [] = displayRects(app)
            % Display the rectangles on screen
            xy = app.XYPosSpinner.Value;
            currRects = app.rects{xy};
            
            % Do nothing in case if no rectangles exist
            if length(currRects) == 0
                return
            end
            
            % Plot currently selected item in green
            currItem = str2num(app.SelectRect.Value);
            for i = 1:length(currRects)
                if i==currItem
                    c = 'g';
                else
                    c = 'r';
                end
                rectangle(app.UIAxes, 'Position', currRects{i}, 'EdgeColor', c)
            end
        end
    
        function img = getFrame(app, channel, t, z, xy)
            % Gets current frame in specified channel. Default values are selected
            if nargin < 2
                channel = app.ChannelSpinner.Value;
            end
            if nargin < 3
                t = app.TimeSpinner.Value;
            end
            if nargin < 4
                z = app.ZPosSpinner.Value;
            end
            if nargin < 5
                xy = app.XYPosSpinner.Value;
            end
            
            app.reader.setSeries(xy-1);
            ix = app.reader.getIndex(z-1, channel- 1, t- 1) + 1;
            img = bfGetPlane(app.reader, ix);
        end

        % -----------------------------------------------------
        % UI FUNCTIONS
        % Related to user interactions
        % -----------------------------------------------------
        function rect = drawRect(app)
            % Initialize drawing of new rectangle
            pan(app.UIAxes,'off');
            zoom(app.UIAxes,'off');
            
            app.rectTopLeft = [];
            app.rectBottomRight = [];
            app.imgHandle.ButtonDownFcn = @(~,e) clickTopLeftCallback(app,e);
            waitfor(app.imgHandle, 'ButtonDownFcn', [])
            
            if isempty(app.rectBottomRight)
                rect = [];
            else
                rect = [uint16(app.rectTopLeft), ...
                        uint16(app.rectBottomRight - app.rectTopLeft)];
            end
                
        end
    
        function [] = clickBottomRightCallback(app, e)
            % Callback used for drawing new rectangle. 
            if e.Button == 3
                % Abort upon right click
                app.imgHandle.ButtonDownFcn = [];
            end
            
            app.rectBottomRight = e.IntersectionPoint(1:2);
            app.imgHandle.ButtonDownFcn = []; % Send signal that we're done
        end
    
        function [] = clickTopLeftCallback(app, e)
            % Callback used for drawing new rectangle. 
            if e.Button == 3
                % Abort upon right click
                app.imgHandle.ButtonDownFcn = [];
            end
            
            p = e.IntersectionPoint(1:2);
            app.rectTopLeft = p;
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, p(1), p(2), 'ro', 'MarkerSize', 10, 'Color','r');
            hold(app.UIAxes, 'off');
            app.imgHandle.ButtonDownFcn = @(~,e) clickBottomRightCallback(app,e);
        end
    
        function [] = updateRectList(app)
            % Update the selection list of rectangles 
            xy = app.XYPosSpinner.Value;
            nbrects = length(app.rects{xy});
            items = cell(nbrects,1);
            for i = 1:nbrects
                items{i} = num2str(i);
            end
            app.SelectRect.Items = items;
            if ~isempty(items)
                app.SelectRect.Value = items{length(items)};
            end
        end
            
        % -----------------------------------------------------
        % OTHER
        % -----------------------------------------------------
        function [out] = flattenCell(app, in)
            % Recursively flattens cell
            out = cell(0);
            for i=1:length(in)
                if iscell(in{i})
                    out = [out, flattenCell(app, in{i})];
                else
                    out = [out, in{i}];
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, imgfile)
            app.callingApp = callingapp;
            
            % Create reader for image access
            bfInitLogging('OFF');
            app.reader = loci.formats.Memoizer(bfGetReader(), 0);
            app.reader.setId(imgfile);
            
            % Initialize rects
            app.rects = cell(app.reader.getSeriesCount, 1);
            
            % Initialize gui stuff
            initializeSpinnerBounds(app)
            initializeLists(app)
            displayImg(app)
        end

        % Button pushed function: PanButton
        function doPan(app, event)
            pan(app.UIAxes,'on');
        end

        % Button pushed function: ZoomButton
        function doZoom(app, event)
            zoom(app.UIAxes,'on');
        end

        % Value changed function: ChannelSpinner, TimeSpinner, 
        % XYPosSpinner, ZPosSpinner
        function doChangeImg(app, event)
            updateRectList(app)            
            displayImg(app)
        end

        % Button pushed function: AddRectangleButton
        function doAddRect(app, event)
            rect = drawRect(app);
            xy = app.XYPosSpinner.Value;
            currcell = app.rects{xy};
            currcell{length(currcell) + 1} = rect;
            app.rects{xy} = currcell;
            updateRectList(app)            
            displayRects(app)
        end

        % Button pushed function: DeleteSelectedButton
        function doDelete(app, event)
            currItem = str2num(app.SelectRect.Value);
            xy = app.XYPosSpinner.Value;
            app.rects{xy}(currItem) = [];
            updateRectList(app);
            displayImg(app);
        end

        % Value changed function: SelectRect
        function doChangeSelection(app, event)
            displayRects(app);
        end

        % Button pushed function: CONFIRMButton
        function doConfirm(app, event)
            if isempty(app.ExcludePos.Value)
                exclude = [];
            else
                exclude = cell2mat(app.ExcludePos.Value);
            end
            
            fullRect = [1, 1, app.reader.getSizeX, app.reader.getSizeY];
            
            for i=1:length(app.rects)
                rect = app.rects{i};
                
                % In case no rectangle was drawn
                if isempty(rect)
                    if ismember(num2str(i), exclude)
                        app.rects{i} = [];
                    else 
                        app.rects{i} = fullRect;
                    end
                end
            end
            
            % Get Position Lookup
            rectsPerPos = zeros(length(app.rects), 1);
            for i=1:length(app.rects)
                if iscell(app.rects{i})
                    rectsPerPos(i) = length(app.rects{i});
                elseif isempty(app.rects{i})
                    rectsPerPos(i) = 0;
                else
                    rectsPerPos(i) = 1;
                end
            end
            app.posLookup = repelem(1:length(app.rects), rectsPerPos);

            
            % Modify attribute of calling app
            app.callingApp.rects = vertcat(flattenCell(app, app.rects));
            app.callingApp.posLookup = app.posLookup;
            doClose(app)
        end

        % Close request function: UIFigure
        function doClose(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @doClose, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PanButton = uibutton(app.UIFigure, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @doPan, true);
            app.PanButton.Text = 'Pan';
            app.ZoomButton = uibutton(app.UIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @doZoom, true);
            app.ZoomButton.Text = 'Zoom';
            app.ExcludePositionsListBoxLabel = uilabel(app.UIFigure);
            app.ExcludePositionsListBoxLabel.Text = 'Exclude Positions ';
            app.ExcludePos = uilistbox(app.UIFigure);
            app.ExcludePos.Multiselect = 'on';
            app.ExcludePos.Value = {'Item 4'};
            app.NavigationPanel = uipanel(app.UIFigure);
            app.NavigationPanel.Title = 'Navigation';
            app.XYPosSpinnerLabel = uilabel(app.NavigationPanel);
            app.XYPosSpinnerLabel.Text = 'XY Pos';
            app.XYPosSpinner = uispinner(app.NavigationPanel);
            app.XYPosSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeImg, true);
            app.XYPosSpinner.Value = 1;
            app.ZPosSpinnerLabel = uilabel(app.NavigationPanel);
            app.ZPosSpinnerLabel.Text = 'Z Pos';
            app.ZPosSpinner = uispinner(app.NavigationPanel);
            app.ZPosSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeImg, true);
            app.ZPosSpinner.Value = 1;
            app.TimeSpinnerLabel = uilabel(app.NavigationPanel);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.NavigationPanel);
            app.TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeImg, true);
            app.TimeSpinner.Value = 1;
            app.ChannelSpinnerLabel = uilabel(app.NavigationPanel);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.NavigationPanel);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeImg, true);
            app.ChannelSpinner.Value = 1;
            app.HandleRectanglesPanel = uipanel(app.UIFigure);
            app.HandleRectanglesPanel.Title = 'Handle Rectangles';
            app.AddRectangleButton = uibutton(app.HandleRectanglesPanel, 'push');
            app.AddRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @doAddRect, true);
            app.AddRectangleButton.Text = 'Add Rectangle';
            app.DeleteSelectedButton = uibutton(app.HandleRectanglesPanel, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @doDelete, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.SelectrectangleListBoxLabel = uilabel(app.HandleRectanglesPanel);
            app.SelectrectangleListBoxLabel.Text = 'Select rectangle';
            app.SelectRect = uilistbox(app.HandleRectanglesPanel);
            app.SelectRect.ValueChangedFcn = createCallbackFcn(app, @doChangeSelection, true);
            app.CONFIRMButton = uibutton(app.UIFigure, 'push');
            app.CONFIRMButton.ButtonPushedFcn = createCallbackFcn(app, @doConfirm, true);
            app.CONFIRMButton.Text = 'CONFIRM';
        end
    end

    methods (Access = public)

        % Construct app
        function app = doExtractRectangles(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end