classdef doSeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PointDetectionPanel      matlab.ui.container.Panel
        AddPointButton           matlab.ui.control.Button
        RemovePointButton        matlab.ui.control.Button
        SegmentationPanel        matlab.ui.container.Panel
        ModifyRegionButton       matlab.ui.control.Button
        SegmentButton            matlab.ui.control.Button
        MergeRegionsButton       matlab.ui.control.Button
        ShowCellNumbersCheckBox  matlab.ui.control.CheckBox
        FrameSelectionPanel      matlab.ui.container.Panel
        TimeframeSpinnerLabel    matlab.ui.control.Label
        TimeframeSpinner         matlab.ui.control.Spinner
        RegionSpinnerLabel       matlab.ui.control.Label
        RegionSpinner            matlab.ui.control.Spinner
        ChannelSpinnerLabel      matlab.ui.control.Label
        ChannelSpinner           matlab.ui.control.Spinner
        ZSpinnerLabel            matlab.ui.control.Label
        ZSpinner                 matlab.ui.control.Spinner
        LabelingPanel            matlab.ui.container.Panel
        RedoLabelsButton         matlab.ui.control.Button
        ChangeLabelButton        matlab.ui.control.Button
        SplitCellButton          matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
    end

    % Global variables
    properties (Access = public)
        imgfile         % Image File that is opened
        res             % Save file (matfile object)
        savefilename    % Save file name
        reader          % bfreader object

        currpoints      % images.roi.Point objects active in current frame
        ax              % Axis object for image plotting
        
        rects           % Rectangles in which interesting regions are
        posLookup       % Map from XY Position to Series in which it is
        
        currbgmask      % Mask of background
        
        xyPos
        zPos
        tPos
        cPos
        currSeries
        
        segMasks
        param
        
        cellFluo
        p
    end
    
    
    methods (Access = private)  
        % ----------------------------------------------------------
        % DISK INTERACTIONS
        % The following functions handle interactions with the 
        % save file on disk
        % ----------------------------------------------------------
        function mask = getSegMask(app, xy, z, t)
            % Returns cell segmentation mask of desired position,
            % or current position if not specified
            if nargin==1
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            if (t<1 || z<1 || xy<1)
                mask = [];
                return
            end
            
            subcell = app.res.segMasks(xy, z, t);
            mask = subcell{1};
        end
    
    
        function [] = setSegMask(app, mask, xy, z, t)
            % Sets cell segmentation mask of desired position,
            % or current position if not specified
            if nargin <= 2
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            subcell = cell(1);
            subcell{1} = mask;
            app.res.segMasks(xy, z, t) = subcell;
        end

    
        function pts = getCellPoints(app, xy, z, t)
            % Returns cell segmentation mask of desired position,
            % or current position if not specified
            if nargin==1
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            subcell = app.res.points(xy, z, t);
            pts = subcell{1};
        end
    
    
        function [] = setCellPoints(app, pts, xy, z, t)
            % Sets cell points of desired position,
            % or current position if not specified
            if nargin <= 2
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            subcell = cell(1);
            subcell{1} = pts;
            app.res.points(xy, z, t) = subcell;
        end

    
        function [] = saveMetadata(app)
            % Saves metadata to res file. Res file has to be open.
            meta.imgfile         = app.imgfile;
            meta.savefilename    = app.savefilename;
            
            meta.zPos            = app.ZSpinner.Value;
            meta.xyPos           = app.RegionSpinner.Value;
            meta.tPos            = app.TimeframeSpinner.Value;
            meta.cPos            = app.ChannelSpinner.Value;
            meta.currSeries      = app.currSeries;
            
            meta.param           = app.param;
            
            meta.rects           = app.rects;
            meta.posLookup       = app.posLookup;
            
            app.res.meta = meta;
        end
    
    
        function [] = loadMetadata(app)
            % Sets metadata from res file. Res file has to be open.
            meta                = app.res.meta;
            app.imgfile         = meta.imgfile;
            app.savefilename    = meta.savefilename;
            
            app.ZSpinner.Value          = meta.zPos;
            app.RegionSpinner.Value     = meta.xyPos;
            app.TimeframeSpinner.Value  = meta.tPos;
            app.ChannelSpinner.Value    = meta.cPos;
            app.currSeries              = meta.currSeries;
            
            app.param          = meta.param;
            
            app.rects       = meta.rects;
            app.posLookup   = meta.posLookup;
        end
    
        % ----------------------------------------------------------
        % ALGORITHMIC COMPONTENTS
        % The following functions handle algorithms used in the program
        % ----------------------------------------------------------
        function [] = findCellCenter(app)
            % Finds cell center of the cells automatically
            bf_img = getFrame(app, app.param.gen.bf_ch);
            
            if app.param.gen.is_bf % Invert if bf image
                bf_img = imcomplement(bf_img);
            end
            
            [rowi, coli] = pointsInCellGUI2(bf_img, app.currbgmask, app.param.pic);
            
            prevMask = getSegMask(app, app.xyPos, app.zPos, app.tPos-1);
            if ~isempty(prevMask)
                [rowi, coli] = ensurePointInAllCells(app, rowi, coli, prevMask);
            end


            setCellPoints(app, [rowi, coli])      
        end
    
    
        function [rowi, coli] = ensurePointInAllCells(~, rowi, coli, prevMask)
            if isempty(prevMask)
                return
            end
            
            allCells = unique(prevMask);
            
            inWhichCell = zeros(length(rowi), 1);
            for i=1:length(rowi)
                inWhichCell(i) = prevMask(coli(i), rowi(i));
            end
           
            
            noPoint = setdiff(allCells, inWhichCell);
            noPointNoBG = noPoint(noPoint > 0);
            for cell=noPointNoBG'
                centroid = regionprops(prevMask==cell, 'Centroid');
                for centr=centroid
                    rowi(length(rowi) + 1) = centr.Centroid(2);
                    coli(length(coli) + 1) = centr.Centroid(1);
                end
                
            end
            
            
        end

    
        function [] = updateCellFluo(app, xy, z, t)
            % Updates cell fluorescence for all channels at the given 
            % position (or the current position if no other is specified)
            if nargin <= 1
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            segMask = getSegMask(app, xy, z, t);
            
            for col = 1:app.reader.getSizeC
                out = zeros(max(segMask, [], 'all'), 1);
                
                frame = getFrame(app, col, t, z, xy);
                for cell = unique(segMask)'
                    if cell <= 0
                        continue
                    end
                    cellMask = segMask == cell;
                    cellSize = sum(cellMask, 'all');
                    totSign = sum(frame(cellMask), 'all');
                    out(cell) = totSign / cellSize;
                end
                
                app.cellFluo{xy, z, t, col} = out;
            end
            
            % Save to disk (TODO: possibly inefficient)
            app.res.cellFluo = app.cellFluo;
            
        end
    
        function corrMask = correctSegMask(app, mask, xy, z, t)
            % Corrects cell assignment numbers of segmentation mask
            % based on the previous mask 
            if nargin <= 2
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            if app.tPos == 1 
                corrMask = mask;
            else
                prev = getSegMask(app, xy, z, t-1);
                
                if isempty(prev)
                    corrMask = mask;
                else
                    corrMask = cellCorrespondance(prev, mask);
                end
            end
        end
    
        function [] = updateBGMask(app)
             % Updates background mask
            fluo = getFrame(app, app.param.gen.fluo_ch);
            app.currbgmask = getBackgroundGUI(fluo, app.param.bg);
        end


        % ----------------------------------------------------------
        % IMAGE DISPLAY
        % The following functions are related to displaying the image
        % with the points and segmentation masks
        % ----------------------------------------------------------
        function img = getFrame(app, channel, t, z, xy)
            % Returns the current image as array
            updatePos(app);
            
            % Gets current frame in specified channel. Default values are selected
            if nargin < 2
                channel = app.cPos;
            end
            if nargin < 3
                t = app.tPos;
            end
            if nargin < 4
                z = app.zPos;
            end
            if nargin < 5
                xy = app.xyPos;
            end
            
            series = app.posLookup(xy);
            app.reader.setSeries(series - 1);

            rect = app.rects{xy};
            ix = app.reader.getIndex(z-1, channel- 1, t- 1) + 1;
            img = bfGetPlane(app.reader, ix, rect(1), rect(2), rect(3), rect(4));
        end
        

        function out = addCurrSegMasks(app, img)
            % Adds the segmentation mask of the current frame to the input image,
            % outputs new image. Returns original image if no mask is present
            
            segMask = getSegMask(app);
            if isempty(segMask)
                out = img;
                return 
            end
            
            rgbLabels = label2rgb(segMask,...
                'jet','w','shuffle');
            alphablend = vision.AlphaBlender('Opacity', .7);
            out = alphablend(rgbLabels, img);
        end
    
    
        function [] = addCellNumbers(app)
            segMask = getSegMask(app);
            if isempty(segMask)
                return 
            end
            
            cells=unique(segMask)';
            for cell=cells
                % Dont label bg and borders
                if cell <= 0
                    continue
                end
                
                centr = regionprops(segMask==cell, 'Centroid');
                centroid = centr.Centroid;
                text(app.ax, centroid(1), centroid(2), num2str(cell), 'FontSize', 20, 'Color', 'r');
            end
            
            
        end
        

        function [] = displayImg(app)
            % Displays current image with points and segmentation if present          

            img = getFrame(app);
            img8bit = uint8(rescale(img, 0, 255));
            rgb = cat(3, img8bit, img8bit, img8bit);

            % Add segmentation if present
            rgbWithSeg = addCurrSegMasks(app, rgb);

            % Keep Zoom if not first display of image
            firstDisplay = isempty(get(app.ax, 'Children'));
            if firstDisplay
                imshow(rgbWithSeg, 'Parent', app.ax)
            else 
                oldxlim = get(app.ax, 'XLim');
                oldylim = get(app.ax, 'YLim');
                imshow(rgbWithSeg, 'Parent', app.ax)
                set(app.ax, 'XLim', oldxlim)
                set(app.ax, 'YLim', oldylim)
            end
                                    
            % Find cell centers
            if isempty(getCellPoints(app))
                findCellCenter(app)
            end
            displayPoints(app)         
            
            % Add points if checked
            if app.ShowCellNumbersCheckBox.Value
                addCellNumbers(app)
            end
        end
    

        function [] = displayPoints(app)
            % Display points on image
            pts = getCellPoints(app);
            rowi = pts(:,1);
            coli = pts(:,2);
            % TODO: Better
            y = rowi;
            x = coli;
            
            app.currpoints = cell(length(x), 1);
            for i=1:length(rowi)
                app.currpoints{i} = drawpoint(...
                    'Parent', app.ax, ...
                    'Position', [x(i) y(i)]);
            end
        end
    
    
        % ----------------------------------------------------------
        % GUI CONTROL 
        % The following functions are used to react to GUI interactions
        % and to save the current state.
        % ----------------------------------------------------------
        function [] = updatePos(app)
            % Updates values of Positions to correspond to Spinner values.
            % To be called every time a spinner is changed
            app.xyPos = app.RegionSpinner.Value;
            app.zPos = app.ZSpinner.Value;
            app.tPos = app.TimeframeSpinner.Value;
            app.cPos = app.ChannelSpinner.Value;
            app.currSeries = app.posLookup(app.xyPos);
        end
        
    
        function [] = saveCurrPoints(app, xyPos, zPos, tPos)
            % Saves currpoints in points object, to be called every time 
            % image is changed to save changes made by user
            npoints = length(app.currpoints);
            tmp = zeros(npoints, 2);
            for i = 1:npoints
                tmp(i,:) = uint16(app.currpoints{i}.Position);
            end
            
            % Flip array to go from x, y to rowi, coli
            setCellPoints(app, fliplr(tmp), xyPos, zPos, tPos);
        end
    

        function clamped = admissibleIndex(app)
            % Ensures that Spinner values are admissible. If non admissible
            % value was set, returns true, else returns false
            
            function [out, changed] = clamp(val, minVal, maxVal)
                % Clamps val between minVal and maxVal, and returns true if 
                % clamping was done
                out = max(minVal, val);
                out = min(maxVal, out);
                changed = out ~= val;
            end
        
            [app.ZSpinner.Value, zClamped] = clamp(...
                app.ZSpinner.Value,...
                1,...
                app.reader.getSizeZ);
            [app.ChannelSpinner.Value, chClamped] = clamp(...
                app.ChannelSpinner.Value,...
                1,...
                app.reader.getSizeC);
            [app.TimeframeSpinner.Value, tClamped] = clamp(...
                app.TimeframeSpinner.Value,...
                1,...
                app.reader.getSizeT);
            [app.RegionSpinner.Value, xyClamped] = clamp(...
                app.RegionSpinner.Value, ...
                1, ...
                length(app.posLookup));
            clamped = zClamped | chClamped | tClamped | xyClamped;
        end
        

        function closestIx = findClosestPoint(app, pos)
            % Finds index of the points in app.currpoints that is closest
            % to the input position. Pos = [x y].
            closestIx = 0;
            closestDist = 1000000000;
            for i = 1:length(app.currpoints)
                if ~isempty(app.currpoints{i})
                    currDist = norm(app.currpoints{i}.Position - pos);
                    if currDist < closestDist
                        closestDist = currDist;
                        closestIx = i;
                    end
                end
            end
        end

    
        % ----------------------------------------------------------
        % STARTUP / LOADING FUNCTIONS    
        % These functions are typically called only after loading of
        % a new file and are related to inializing the GUI.
        % ----------------------------------------------------------
        function [] = initializeSpinners(app)
            % Inizializes Spinners to one
            app.ZSpinner.Value = 1;
            app.RegionSpinner.Value = 1;
            app.TimeframeSpinner.Value = 1;
            app.ChannelSpinner.Value = 1;
            app.currSeries = 1;
        end
    
        function [] = initializeParams(app, bf_ch, fluo_ch, is_bf)
            app.param.gen.fluo_ch = fluo_ch;
            app.param.gen.bf_ch = bf_ch;
            app.param.gen.is_bf = is_bf;
            
            app.param.rect.margin = 0;
            app.param.rect.threshFactor = 3;
            
            % Watershed parameters
            ws.smooth = 2;
            ws.center_dilate = 2;
            app.param.ws = ws;
            
            % Get background parameters
            bg.dilate = 10;
            bg.smoothing = 5;
            app.param.bg = bg;
            
            % Points in cell parameters
            pic.erode=2;
            pic.dilate=4;
            pic.close=6;
            pic.blur=3;
            pic.distsmooth=2;
            app.param.pic=pic;
        end
       

        function [] = getRects(app)
            % Get rectangles which correspond to regions of interest
            nb_series = app.reader.getSeriesCount;
            tmp = cell(nb_series, 1);
            for i=1:app.reader.getSeriesCount
                tmp{i} = nd2PosCellRegions(...
                    app.imgfile, i, app.param.gen.fluo_ch, ...
                    app.param.rect.margin, app.param.rect.threshFactor);
            end
            
            % Create unique ID for every rectangle, create lookup table to identify in
            % which position the rectangle is
            rectsPerPos = zeros(length(tmp), 1);
            for i=1:length(tmp)
                rectsPerPos(i) = length(tmp{i});
            end
            app.posLookup = repelem(1:length(tmp), rectsPerPos);
            app.rects = vertcat(tmp{:});
        end
    
        
        function [] = noRects(app)
            % In case no rectangles are calculated
            fullRect = [1, 1, app.reader.getSizeX, app.reader.getSizeY];
            nb_series = app.reader.getSeriesCount;
            tmp = cell(nb_series, 1);
            for i = length(tmp)
                tmp{i} = fullRect;
            end
            app.rects = tmp;
            
            app.posLookup = 1:nb_series;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgfile, savefile, dorect, bf_ch, fluo_ch, is_bf)
            
            [~, ~, ext] = fileparts(imgfile);
            
            switch ext
                case '.mldatx'
                    app.res = matfile(imgfile, 'Writable', true);
                    loadMetadata(app)
                    
                    % Create reader for image access
                    bfInitLogging('OFF');
                    app.reader = loci.formats.Memoizer(bfGetReader(), 0);
                    app.reader.setId(app.imgfile);
                    
                case '.nd2'
                    initializeSpinners(app)
                    initializeParams(app, bf_ch, fluo_ch, is_bf)
                    
                    app.imgfile = imgfile;
                    app.savefilename = savefile;
                    app.res = matfile(app.savefilename, 'Writable', true);
                    
                    % Create reader for image access
                    bfInitLogging('OFF');
                    app.reader = loci.formats.Memoizer(bfGetReader(), 0);
                    app.reader.setId(app.imgfile);
                    
                    % Calculate rectangles containing cells
                    if dorect
                        getRects(app)
                    else
                        noRects(app)
                    end
                                        
                    % Initialize empty result cells in output file
                    app.res.points = cell(...
                        app.reader.getSeriesCount, ...
                        app.reader.getSizeZ, ...
                        app.reader.getSizeT);
                    app.res.segMasks = cell(...
                        app.reader.getSeriesCount, ...
                        app.reader.getSizeZ, ...
                        app.reader.getSizeT);
                    app.cellFluo = cell(...
                        app.reader.getSeriesCount, ...
                        app.reader.getSizeZ, ...
                        app.reader.getSizeT, ...
                        app.reader.getSizeC);

                    
                    % Save metadata
                    saveMetadata(app)
            end
            
            % Update App Positions
            updatePos(app)
            
            % Since UIAxis doesn't support the function drawpoints, a custom
            % axis is created manually
            %app.p = uipanel('Title','Panel', 'Position',[.025 .3 .95 .6]);
            app.ax = axes(app.Panel);
            %app.ax.Position = [0.025    0.30    0.95    0.6];

            updateBGMask(app)
            displayImg(app)
            
        end

        % Value changed function: ChannelSpinner, RegionSpinner, 
        % TimeframeSpinner, ZSpinner
        function doChangeSpinner(app, event)
            oldxyPos = app.RegionSpinner.Value;
            oldzPos = app.ZSpinner.Value;
            oldtPos = app.TimeframeSpinner.Value;
            
            switch event.Source
                case app.ZSpinner
                    oldzPos = event.PreviousValue;
                case app.RegionSpinner
                    oldxyPos = event.PreviousValue;
                case app.TimeframeSpinner
                    oldtPos = event.PreviousValue;
            end
            
            saveCurrPoints(app, oldxyPos, oldzPos, oldtPos)
            clamped = admissibleIndex(app);
            if ~clamped
                displayImg(app)
            end
            updatePos(app)
            
            updateBGMask(app)
        end

        % Button pushed function: AddPointButton
        function doAddPoint(app, event)
            app.currpoints{length(app.currpoints) + 1} = drawpoint(app.ax);
        end

        % Button pushed function: RemovePointButton
        function doRemovePoint(app, event)
            % Create drawpoint object to get position to delete
            delPoint = drawpoint(app.ax);
            delPos = delPoint.Position;
            delete(delPoint)
            
            % Find point closest to the clicked point, remove it
            closestIx = findClosestPoint(app, delPos);
            app.currpoints(closestIx) = [];
            delete(delPoint);
            
            saveCurrPoints(app, app.xyPos, app.zPos, app.tPos);
            displayImg(app);
        end

        % Close request function: UIFigure
        function doClose(app, event)
            app.reader.close();
            saveMetadata(app)
            delete(app)
        end

        % Button pushed function: SegmentButton
        function doSegment(app, event)
            saveCurrPoints(app, app.xyPos, app.zPos, app.tPos)
            
            bfImg = getFrame(app, app.param.gen.bf_ch);
            if app.param.gen.is_bf % Invert if bf image
                bfImg = imcomplement(bfImg);
            end
            cellcenters = getCellPoints(app);
            seg = cellWatershedGUI2(bfImg, app.currbgmask, cellcenters(:,1), ...
                cellcenters(:,2), app.param.ws);
            corrMask = correctSegMask(app, seg);
            
            setSegMask(app, corrMask)
            updateCellFluo(app)
            displayImg(app);
        end

        % Button pushed function: ModifyRegionButton
        function doModifyRegion(app, event)
            % Get region to modify
            poi = drawpoint(app.ax);
            poiPos = uint16(poi.Position);
            delete(poi)
            currMask = getSegMask(app);
            if isempty(currMask)
                return 
            end
            regionNb = currMask(poiPos(2), poiPos(1));

            form = drawfreehand(app.ax);
            currMask(form.createMask) = regionNb;
            delete(form)
            
            corrected = correctSegMask(app, currMask);
            setSegMask(app, corrected)
            displayImg(app)
            
            updateCellFluo(app)
            
        end

        % Button pushed function: MergeRegionsButton
        function doMerge(app, event)
            currMask = getSegMask(app);
            if isempty(currMask)
                return 
            end
            
            % Get first region to merge
            poi1 = drawpoint(app.ax);
            poiPos1 = uint16(poi1.Position);
            delete(poi1)
            regionNb1 = currMask(poiPos1(2), poiPos1(1));
            
            % Get second region to merge
            poi2 = drawpoint(app.ax);
            poiPos2 = uint16(poi2.Position);
            delete(poi2)
            regionNb2 = currMask(poiPos2(2), poiPos2(1));
            
            % Set number of second region to first
            currMask(currMask == regionNb2) = regionNb1;
            corrected = correctSegMask(app, currMask);
            
            % Correct numbers to corresponding cells
            setSegMask(app, corrected)
            displayImg(app);
            updateCellFluo(app)
        end

        % Value changed function: ShowCellNumbersCheckBox
        function doShowCellNumbers(app, event)
            displayImg(app)            
        end

        % Button pushed function: RedoLabelsButton
        function doRedoLabels(app, event)
            currMask = getSegMask(app);
            if isempty(currMask)
                return 
            end
            corrected = correctSegMask(app, currMask);
            
            % Correct numbers to corresponding cells
            setSegMask(app, corrected)
            displayImg(app);
            updateCellFluo(app)
            
        end

        % Button pushed function: ChangeLabelButton
        function doChangeLabel(app, event)
            poi = drawpoint(app.ax);
            poiPos = uint16(poi.Position);
            delete(poi)
            
            input = inputdlg('Enter new cell number');
            newNb = str2num(input{1});
            if isempty(newNb)
                return
            end
            
            currMask = getSegMask(app);
            regionNb = currMask(poiPos(2), poiPos(1));
            
            currMask(currMask == regionNb) = newNb;
            setSegMask(app, currMask);
            displayImg(app);
            updateCellFluo(app)

        end

        % Button pushed function: SplitCellButton
        function doSplitCell(app, event)
            poi = drawpoint(app.ax);
            poiPos = uint16(poi.Position);
            delete(poi)
            
            currMask = getSegMask(app);
            regionNb = currMask(poiPos(2), poiPos(1));
            
            form = drawfreehand(app.ax);
            toEdit = currMask == regionNb & form.createMask;
            delete(form); 
            
            currMask(toEdit) = max(currMask, [], 'all') + 1;
            setSegMask(app, currMask);
            displayImg(app);
            updateCellFluo(app)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @doClose, true);
            app.PointDetectionPanel = uipanel(app.UIFigure);
            app.PointDetectionPanel.Title = 'Point Detection';
            app.AddPointButton = uibutton(app.PointDetectionPanel, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @doAddPoint, true);
            app.AddPointButton.Text = 'Add Point';
            app.RemovePointButton = uibutton(app.PointDetectionPanel, 'push');
            app.RemovePointButton.ButtonPushedFcn = createCallbackFcn(app, @doRemovePoint, true);
            app.RemovePointButton.Text = 'Remove Point';
            app.SegmentationPanel = uipanel(app.UIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.ModifyRegionButton = uibutton(app.SegmentationPanel, 'push');
            app.ModifyRegionButton.ButtonPushedFcn = createCallbackFcn(app, @doModifyRegion, true);
            app.ModifyRegionButton.Text = 'Modify Region';
            app.SegmentButton = uibutton(app.SegmentationPanel, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @doSegment, true);
            app.SegmentButton.Text = 'Segment';
            app.MergeRegionsButton = uibutton(app.SegmentationPanel, 'push');
            app.MergeRegionsButton.ButtonPushedFcn = createCallbackFcn(app, @doMerge, true);
            app.MergeRegionsButton.Text = 'Merge Regions';
            app.ShowCellNumbersCheckBox = uicheckbox(app.SegmentationPanel);
            app.ShowCellNumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @doShowCellNumbers, true);
            app.ShowCellNumbersCheckBox.Text = 'Show Cell Numbers';
            app.FrameSelectionPanel = uipanel(app.UIFigure);
            app.FrameSelectionPanel.Title = 'Frame Selection';
            app.TimeframeSpinnerLabel = uilabel(app.FrameSelectionPanel);
            app.TimeframeSpinnerLabel.Text = 'Timeframe';
            app.TimeframeSpinner = uispinner(app.FrameSelectionPanel);
            app.TimeframeSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeSpinner, true);
            app.RegionSpinnerLabel = uilabel(app.FrameSelectionPanel);
            app.RegionSpinnerLabel.Text = 'Region';
            app.RegionSpinner = uispinner(app.FrameSelectionPanel);
            app.RegionSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeSpinner, true);
            app.ChannelSpinnerLabel = uilabel(app.FrameSelectionPanel);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.FrameSelectionPanel);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeSpinner, true);
            app.ZSpinnerLabel = uilabel(app.FrameSelectionPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.FrameSelectionPanel);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeSpinner, true);
            app.LabelingPanel = uipanel(app.UIFigure);
            app.LabelingPanel.Title = 'Labeling';
            app.RedoLabelsButton = uibutton(app.LabelingPanel, 'push');
            app.RedoLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @doRedoLabels, true);
            app.RedoLabelsButton.Text = 'Redo Labels';
            app.ChangeLabelButton = uibutton(app.LabelingPanel, 'push');
            app.ChangeLabelButton.ButtonPushedFcn = createCallbackFcn(app, @doChangeLabel, true);
            app.ChangeLabelButton.Text = 'Change Label';
            app.SplitCellButton = uibutton(app.LabelingPanel, 'push');
            app.SplitCellButton.ButtonPushedFcn = createCallbackFcn(app, @doSplitCell, true);
            app.SplitCellButton.Text = 'Split Cell';
            app.Panel = uipanel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = doSeg(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end