classdef doSegComp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PointDetectionPanel         matlab.ui.container.Panel
        AddPointButton              matlab.ui.control.Button
        RemovePointButton           matlab.ui.control.Button
        RedetectButton              matlab.ui.control.Button
        SegmentationPanel           matlab.ui.container.Panel
        AddtoRegionButton           matlab.ui.control.Button
        SegmentButton               matlab.ui.control.Button
        MergeRegionsButton          matlab.ui.control.Button
        NewCellinAreaButton         matlab.ui.control.Button
        RedrawCellButton_2          matlab.ui.control.Button
        BorderWidthSpinnerLabel     matlab.ui.control.Label
        BorderWidthSpinner          matlab.ui.control.Spinner
        FrameSelectionPanel         matlab.ui.container.Panel
        TimeframeSpinnerLabel       matlab.ui.control.Label
        TimeframeSpinner            matlab.ui.control.Spinner
        RegionSpinnerLabel          matlab.ui.control.Label
        RegionSpinner               matlab.ui.control.Spinner
        ChannelSpinnerLabel         matlab.ui.control.Label
        ChannelSpinner              matlab.ui.control.Spinner
        ZSpinnerLabel               matlab.ui.control.Label
        ZSpinner                    matlab.ui.control.Spinner
        PositionNbrEditFieldLabel   matlab.ui.control.Label
        PosNumber                   matlab.ui.control.NumericEditField
        LabelingPanel               matlab.ui.container.Panel
        RedoLabelsButton            matlab.ui.control.Button
        ChangeLabelButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        PanButton                   matlab.ui.control.Button
        ZoomButton                  matlab.ui.control.Button
        ExportPanel                 matlab.ui.container.Panel
        PlotCurrentChannelButton    matlab.ui.control.Button
        ShowPointsCheckBox          matlab.ui.control.CheckBox
        ShowSegMask                 matlab.ui.control.CheckBox
        ExportCurrentChannelButton  matlab.ui.control.Button
        SnakesPanel                 matlab.ui.container.Panel
        SnakeonCellButton           matlab.ui.control.Button
        ShowCellNumbersCheckBox     matlab.ui.control.CheckBox
        WhattodoLabel               matlab.ui.control.Label
        todo                        matlab.ui.control.TextArea
        BrushDrawButton             matlab.ui.control.Button
        BrushSizeSpinnerLabel       matlab.ui.control.Label
        BrushSizeSpinner            matlab.ui.control.Spinner
    end

    % Global variables
    properties (Access = public)
        imgfile         % Image File that is opened
        res             % Save file (matfile object)
        savefilename    % Save file name
        reader          % bfreader object
        
        cellpoints      % has cell points
        rects           % Rectangles in which interesting regions are
        posLookup       % Map from XY Position to Series in which it is
        
        xyPos           % Current positions
        zPos
        tPos
        cPos
        currSeries
        
        param           % Parameter object
        
        imgHandle       % Handle of image for assigning callbacks
        
        clickPos        % Variables used in click callbacks
        clickPath       % "
        
        ax              % Contains axis object (UIAxes for <2018b, 
                        % Axes otherwise)
        
        vignette        % Contains first image for vignette removal
                        
        currbgmask      % Mask of background
        currdtr         % Contains distance transform of current
                        % image
        currim          % Currently displayed image
        currim8bit      % Currently displayed image in 8bit format
        currfluo        % Current fluorescent image
        currbf          % Current brightfield image
        currmask        % Current segmentation mask
        
        nextim          % Next image 
        nextim8bit
        nextfluo
        nextbf
        nextmask
        
        previm
        previm8bit
        prevfluo
        prevbf
        prevmask
    end
    
    
    methods (Access = private)
        % ----------------------------------------------------------
        % DISK INTERACTIONS
        % The following functions handle interactions with the
        % save file on disk
        % ----------------------------------------------------------
        function mask = getSegMask(app, xy, z, t)
            % Returns cell segmentation mask of desired position,
            % or current position if not specified
            if nargin==1
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            if (t<1 || z<1 || xy<1)
                mask = [];
                return
            end
            
            subcell = app.res.segMasks(xy, z, t);
            mask = subcell{1};
        end
        
        
        function [] = setSegMask(app, mask, xy, z, t)
            % Sets cell segmentation mask of desired position,
            % or current position if not specified
            if nargin <= 2
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
                
                app.currmask = mask;
            end
            
            subcell = cell(1);
            subcell{1} = mask;
            app.res.segMasks(xy, z, t) = subcell;
        end
        
        
        function pts = getCellPoints(app, xy, z, t)
            % Returns cell points of desired position,
            % or current position if not specified
            if nargin==1
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            pts = app.cellpoints{xy, z, t};
        end
        
        
        function [] = setCellPoints(app, pts, xy, z, t)
            % Sets cell points of desired position,
            % or current position if not specified
            if nargin <= 2
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            app.cellpoints{xy, z, t} = pts;
        end
        
        
        function [] = saveMetadata(app)
            % Saves metadata to res file. Res file has to be open.
            meta.imgfile         = app.imgfile;
            meta.savefilename    = app.savefilename;
            
            meta.zPos            = app.ZSpinner.Value;
            meta.xyPos           = app.RegionSpinner.Value;
            meta.tPos            = app.TimeframeSpinner.Value;
            meta.cPos            = app.ChannelSpinner.Value;
            meta.currSeries      = app.currSeries;
            
            meta.param           = app.param;
            
            meta.rects           = app.rects;
            meta.posLookup       = app.posLookup;
            
            app.res.meta = meta;
            
            app.res.cellpoints = app.cellpoints;
        end
        
        
        function [] = loadMetadata(app)
            % Sets metadata from res file. Res file has to be open.
            meta                = app.res.meta;
            if ~isfile(meta.imgfile)
                uialert(app.UIFigure, ...
                    "ND2 file corresponding to analysis not found. Please specify the ND2 File.",...
                    "ND2 File Not Found")
                [filename, path] = uigetfile('*.nd2', 'Select Image file to load.');
                
                app.imgfile = fullfile(path, filename);
            else
                app.imgfile         = meta.imgfile;
            end
            %app.savefilename    = meta.savefilename;
            
            app.ZSpinner.Value          = meta.zPos;
            app.RegionSpinner.Value     = meta.xyPos;
            app.TimeframeSpinner.Value  = meta.tPos;
            app.ChannelSpinner.Value    = meta.cPos;
            app.currSeries              = meta.currSeries;
            
            app.param          = meta.param;
            
            app.rects       = meta.rects;
            app.posLookup   = meta.posLookup;
            
            app.cellpoints = app.res.cellpoints;
        end
        
        
        % ----------------------------------------------------------
        % ALGORITHMIC COMPONTENTS
        % The following functions handle algorithms used in the program
        % ----------------------------------------------------------
        function [] = findDtr(app)
            % Finds distance transform of current frame
            bf_img = app.currbf;
            
            if app.param.gen.is_bf % Invert if bf image
                bf_img = imcomplement(bf_img);
            end

            app.currdtr = getDistTransform(bf_img, app.param.pic);
        end
        
        
        function [] = findCellCenter(app)
            % Finds cell center of the cells automatically
            [rowi, coli] = pointsInCellGUI3(app.currdtr, app.currbgmask, app.param.pic);
            
            prevMask = getSegMask(app, app.xyPos, app.zPos, app.tPos-1);
            if ~isempty(prevMask)
                [rowi, coli] = ensurePointInAllCells(app, rowi, coli, prevMask);
            end
            
            % Make sure that row/col are column vectors
            [r, ~] = size(rowi);
            if r==1
                rowi = rowi';
                coli = coli';
            end
            
            setCellPoints(app, [rowi, coli])
        end
        
        
        function [rowi, coli] = ensurePointInAllCells(~, rowi, coli, prevMask)
            % Considers previous segmentation mask, verifies that every cell
            % that was previously found has at least one point within its body.
            % If that's not the case, put a point at the centroid
            
            if isempty(prevMask)
                return
            end
            
            allCells = unique(prevMask);
            
            % Find in which cell every point is
            inWhichCell = zeros(length(rowi), 1);
            for i=1:length(rowi)
                inWhichCell(i) = prevMask(rowi(i), coli(i));
            end
            
            % Identify cells with no point (that arent BG)
            noPoint = setdiff(allCells, inWhichCell);
            noPointNoBG = noPoint(noPoint > 0);
            for cell=noPointNoBG'
                centroid = regionprops(prevMask==cell, 'Centroid');
                
                for i=1:length(centroid)
                    rowi(length(rowi) + 1) = uint16(centroid(i).Centroid(2));
                    coli(length(coli) + 1) = uint16(centroid(i).Centroid(1));
                end
            end
        end
        
        
        function [] = updateCellFluo(app, xy, z, t)
            % Updates cell fluorescence for all channels at the given
            % position (or the current position if no other is specified)
            if nargin <= 1
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            segMask = app.currmask;
            if isempty(segMask)
                return
            end
            
            for col = 1:app.reader.getSizeC
                tstart = cputime;
                
                frame = getFrame(app, col, t, z, xy);
                
                % Calculate Area and Fluorescence using Splitapply
                tmid1 = cputime;
                
                [g, id] = findgroups(segMask(:));
                inner = @(a) deal(length(a), sum(a));
                [c, s] = splitapply(inner, frame(:), g);
                
                maxCellNbr = max(segMask(:));
                fluo = NaN(maxCellNbr,1);
                area = NaN(maxCellNbr,1);
                nobg = id>0;
                fluo(id(nobg)) = s(nobg);
                area(id(nobg)) = c(nobg);
                
                tmid2 = cputime;
                
                % Write to disk
                subcell = cell(1);
                subcell{1} = fluo;
                app.res.cellFluo(xy, z, t, col) = subcell;
                
                subcell = cell(1);
                subcell{1} = area;
                app.res.cellArea(xy, z, t, col) = subcell;
                
%                 tend = cputime;
%                 ttot = tend-tstart;
%                 disp("Percent getFrame")
%                 disp((tmid1-tstart)/ttot)
%                 disp("Percent create matrix")
%                 disp((tmid2-tmid1)/ttot)
%                 disp("Percent write to disk")
%                 disp((tend-tmid2)/ttot)

            end            
        end
        
        function corrMask = correctSegMask(app, mask, xy, z, t)
            % Corrects cell assignment numbers of segmentation mask
            % based on the previous mask
            if nargin <= 2
                xy = app.xyPos;
                z = app.zPos;
                t = app.tPos;
            end
            
            if app.tPos == 1
                corrMask = mask;
            else
                prev = getSegMask(app, xy, z, t-1);
                
                if isempty(prev)
                    corrMask = mask;
                else
                    corrMask = cellCorrespondance(prev, mask);
                end
            end
        end
        
        function [] = updateBGMask(app)
            % Updates background mask
            app.currbgmask = getBackgroundGUI(app.currim, app.vignette, app.param.bg);
        end
            
        function [] = updateVignette(app)
            % Todo: Make sigma for vignette changeable
            firstimg = getFrame(app, app.param.gen.bf_ch, 1);
            app.vignette = imgaussfilt(firstimg, 500);
        end
        
        
        % ----------------------------------------------------------
        % IMAGE DISPLAY
        % The following functions are related to displaying the image
        % with the points and segmentation masks
        % ----------------------------------------------------------
        function img = getFrame(app, channel, t, z, xy)
            % Returns the current image as array
            updatePos(app);
            
            % Gets current frame in specified channel. Default values are selected
            if nargin < 2
                channel = app.cPos;
            end
            if nargin < 3
                t = app.tPos;
            end
            if nargin < 4
                z = app.zPos;
            end
            if nargin < 5
                xy = app.xyPos;
            end
            
            series = app.posLookup(xy);
            app.reader.setSeries(series - 1);
            
            rect = app.rects{xy};
            ix = app.reader.getIndex(z-1, channel- 1, t- 1) + 1;
            img = bfGetPlane(app.reader, ix, rect(1), rect(2), rect(3), rect(4));
        end
        
        function [] = loadImageToMemory(app)
            app.currim = getFrame(app);
            app.currim8bit = uint8(rescale(app.currim, 0, 255));
            app.currbf = getFrame(app, app.param.gen.bf_ch);
            app.currfluo = getFrame(app, app.param.gen.fluo_ch);
            app.currmask = getSegMask(app);
            
        end
        
        function [] = loadNextToMemory(app)
            app.currim = app.nextim;
            app.currim8bit = app.nextim8bit;
            app.currbf = app.nextbf;
            app.currfluo = app.nextfluo;
            app.currmask = app.nextmask;
        end
    
        function [] = loadPreviousToMemory(app)
            app.currim = app.previm;
            app.currim8bit = app.previm8bit;
            app.currbf = app.prevbf;
            app.currfluo = app.prevfluo;
            app.currmask = app.prevmask;
        end
    
        function [] = anticipate(app)
            if app.tPos-1>0
                app.previm = getFrame(app, app.cPos, app.tPos-1);
                app.previm8bit = uint8(rescale(app.previm, 0, 255));
                app.prevbf = getFrame(app, app.param.gen.bf_ch, app.tPos-1);
                app.prevfluo = getFrame(app, app.param.gen.fluo_ch, app.tPos-1);
                app.prevmask = getSegMask(app, app.xyPos, app.zPos, app.tPos-1);
            end
            if app.tPos+1 <= app.reader.getSizeT
                app.nextim = getFrame(app, app.cPos, app.tPos+1);
                app.nextim8bit = uint8(rescale(app.nextim, 0, 255));
                app.nextbf = getFrame(app, app.param.gen.bf_ch, app.tPos+1);
                app.nextfluo = getFrame(app, app.param.gen.fluo_ch, app.tPos+1);
                app.nextmask = getSegMask(app, app.xyPos, app.zPos, app.tPos+1);
            end
        end

        function out = addCurrSegMasks(app, img)
            % Adds the segmentation mask of the current frame to the input image,
            % outputs new image. Returns original image if no mask is present.
            if app.ShowSegMask.Value == 0
                out = img;
                return
            end
            
            segMask = app.currmask;
            if isempty(segMask)
                out = img;
                return
            end
            
            rgbLabels = label2rgb(segMask,...
                'jet','w','shuffle');
            
            out = img * .7 + rgbLabels * .3;

        end
    
        function [img] = addCellNumbers(app, img)
            % Adds cell numbers on image display
            segMask = app.currmask;
            if isempty(segMask) || ~app.ShowCellNumbersCheckBox.Value
                return
            end
            
            cells=unique(segMask)';
            
            x = zeros(length(cells), 1);
            y = zeros(length(cells), 1);
            
            for i=1:length(cells)
                cell=cells(i);
                % Dont label bg and borders
                if cell <= 0
                    continue
                end
                
                cellMask = segMask==cell;
                %cellMask = imdilate(cellMask, strel('diamond', 2));
                %centr = regionprops(cellMask, 'Centroid');
                %centroid = centr.Centroid;
                
                % Approximate centroid using 10 randomly sampled pixels of the segment
                linidx = find(cellMask);
                sampleidx = randsample(linidx, 5, true);
                [rowi, coli] = ind2sub(size(cellMask), sampleidx);
                x(i) = mean(coli);
                y(i) = mean(rowi);
                
                %text(app.ax, mean(y), mean(x), num2str(cell), 'FontSize', 20, 'Color', 'g');
            end
            
            img = insertText(img, [x, y], cells, 'FontSize', 16, 'TextColor', 'g',...
                             'BoxOpacity', 0, 'AnchorPoint', 'Center');
        end

        function [mask] = freehandDraw(app, linewidth)
            pan(app.ax, 'off')
            zoom(app.ax, 'off')
            
            l = drawfreehand(app.ax, 'FaceAlpha', 0, 'Closed', false, ...
                'LineWidth', linewidth);
            
            npoints = size(l.Position, 1);
            if npoints==1
                mask = [];
                delete(l)
                return
            end
            
            mask = createMask(l);
            strelsize = floor(linewidth/2);
            % disp(strelsize)
            mask = imdilate(mask, strel('disk', strelsize, 0));
            delete(l)
        end
    
        function [] = say(app, text)
            % Displays text in window
            app.todo.Value = text;
        end
        
        function [] = displayImg(app)
            % Displays current image with points and segmentation if present
            
            
            % Save old zoom
            oldXLim = app.ax.XLim;
            oldYLim = app.ax.YLim;
            
            img8bit = app.currim8bit;
            %rgb = cat(3, img8bit, img8bit, img8bit);
            
            t2 = cputime;
            rgb = addCurrSegMasks(app, img8bit);
            t3 = cputime;
            rgb = addPoints(app, rgb);
            t4 = cputime;
            rgb = addCellNumbers(app, rgb);
            t5 = cputime;
            
            if isempty(app.imgHandle)
                app.imgHandle = imshow(rgb, 'Parent', app.ax);
            else 
                app.imgHandle.CData = rgb;
            end
            
            t6 = cputime;

            % Recreate old zoom if not first display
            if ~(oldXLim(1)==0 && oldXLim(2)==1)
                app.ax.XLim = oldXLim;
                app.ax.YLim = oldYLim;
            end
            
%             disp("Add seg mask")
%             disp(t3-t2)
%             disp("Display Points")
%             disp(t4-t3)
%             disp("Add numbers")
%             disp(t5-t4)
%             disp("Display")
%             disp(t6-t5)
        end
        
        
%         function [] = displayPoints(app)
%             % Display points on image
%             if ~app.ShowPointsCheckBox.Value
%                 return
%             end
%             
%             pts = getCellPoints(app);
%             rowi = pts(:,1);
%             coli = pts(:,2);
%             y = rowi;
%             x = coli;
%             
%             hold(app.ax, 'on')
%             for i=1:length(rowi)
%                 plot(app.ax, x(i), y(i), 'r*', 'LineWidth', 2, 'MarkerSize', 15);
%             end
%             hold(app.ax, 'off')
%         end
        function [img] = addPoints(app,img)
            % Embeds current points in input image
            if ~app.ShowPointsCheckBox.Value
                return
            end
            if isempty(getCellPoints(app))
                findCellCenter(app)
            end
            
            pts = getCellPoints(app);
            rowi = pts(:,1);
            coli = pts(:,2);
            y = rowi;
            x = coli;
            img = insertShape(img, 'FilledCircle', [x, y, 3*ones(length(x), 1)], 'Color', 'r');
%             img = insertMarker(img, [x, y], 'x', 'Size', 4, ...
%                                'Color', 'r');
        end
        
        % ----------------------------------------------------------
        % GUI CONTROL
        % The following functions are used to react to GUI interactions
        % and to save the current state.
        % ---------------------------------------------------------
        function [] = disableAll(app)
            app.UIFigure.KeyPressFcn = @(source, event) [];
            app.AddPointButton.Enable = false;
            app.RemovePointButton.Enable = false;
            app.RedetectButton.Enable = false;
            app.AddtoRegionButton.Enable = false;
            app.SegmentButton.Enable = false;
            app.MergeRegionsButton.Enable = false;
            app.NewCellinAreaButton.Enable = false;
            app.RedrawCellButton_2.Enable = false;
            app.BorderWidthSpinner.Enable = false;
            app.TimeframeSpinner.Enable = false;
            app.RegionSpinner.Enable = false;
            app.ChannelSpinner.Enable = false;
            app.ZSpinner.Enable = false;
            app.RedoLabelsButton.Enable = false;
            app.ChangeLabelButton.Enable = false;
            app.PlotCurrentChannelButton.Enable = false;
            app.ExportCurrentChannelButton.Enable = false;
            %app.ShowCellNumbersCheckBox.Enable = false;
            app.BrushDrawButton.Enable = false;
            app.BrushSizeSpinner.Enable = false;     
            %app.ShowPointsCheckBox.Enable = false;
            %app.ShowSegMask.Enable = false;
        end
    
        function [] = activateAll(app)
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @shortcut, true);
            app.AddPointButton.Enable = true;
            app.RemovePointButton.Enable = true;
            app.RedetectButton.Enable = true;
            app.AddtoRegionButton.Enable = true;
            app.SegmentButton.Enable = true;
            app.MergeRegionsButton.Enable = true;
            app.NewCellinAreaButton.Enable = true;
            app.RedrawCellButton_2.Enable = true;
            app.BorderWidthSpinner.Enable = true;
            app.TimeframeSpinner.Enable = true;
            app.RegionSpinner.Enable = true;
            app.ChannelSpinner.Enable = true;
            app.ZSpinner.Enable = true;
            app.RedoLabelsButton.Enable = true;
            app.ChangeLabelButton.Enable = true;
            app.PlotCurrentChannelButton.Enable = true;
            app.ExportCurrentChannelButton.Enable = true;
            %app.ShowCellNumbersCheckBox.Enable = true;
            app.BrushDrawButton.Enable = true;
            app.BrushSizeSpinner.Enable = true;            
            %app.ShowPointsCheckBox.Enable = true;
            %app.ShowSegMask.Enable = true;
        end
        
        
        function [] = updatePos(app)
            % Updates values of Positions to correspond to Spinner values.
            % To be called every time a spinner is changed
            app.xyPos = app.RegionSpinner.Value;
            app.zPos = app.ZSpinner.Value;
            app.tPos = app.TimeframeSpinner.Value;
            app.cPos = app.ChannelSpinner.Value;
            app.currSeries = app.posLookup(app.xyPos);
        end
        
        
        function [] = imClickCallback(app, e)
            % Only to be called from getClickPoint
            if e.Button ~= 1
                % Abort upon right click
                app.imgHandle.ButtonDownFcn = [];
                return
            end
            
            app.clickPos = e.IntersectionPoint;
            app.imgHandle.ButtonDownFcn = []; % Sends signal that we're done
        end
        
        
        function [x, y] = getClickPoint(app)
%             % Gets location of click
            %pan(app.ax,'off');
            %zoom(app.ax,'off');
            
            app.clickPos = [];
            app.imgHandle.ButtonDownFcn = @(~,e) imClickCallback(app,e);
            
            % Waits until ButtonDownFcn is set to empty again, indicating
            % that the callback was called and the clickPos modified
            waitfor(app.imgHandle, 'ButtonDownFcn', [])
            
            if isempty(app.clickPos)
                x=[];
                y=[];
            else
                x = uint16(app.clickPos(1));
                y = uint16(app.clickPos(2));
            end
%             roi = drawpoint(app.ax , 'Color', 'g');
%             x = roi.Position(1);
%             y = roi.Position(2);
%             delete(roi)
            enableDefaultInteractivity(app.ax);
        end
        
        function [] = imClickAreaCallback(app, e)
            % Only to be called from getClickArea
            
            % right click
            if e.Button ~= 1
                % Send signal that we're done
                app.imgHandle.ButtonDownFcn = [];
            else
                newClick = e.IntersectionPoint;
                app.clickPath = [app.clickPath; newClick];
            end
            
        end
        
        function [mask] = getClickArea(app)
            pan(app.ax,'off');
            zoom(app.ax, 'off');
            
            % UNCOMMENT FOR MULTICLICK
            form = drawfreehand(app.ax, 'Multiclick', true);
            mask = form.createMask;
            delete(form)
            enableDefaultInteractivity(app.ax);
            
            % UNCOMMENT FOR PRESS AND DRAW
%             form = drawfreehand(app.ax, 'InteractionsAllowed', 'None');
%             mask = form.createMask;
%             delete(form)
%             enableDefaultInteractivity(app.ax);
        end
    
    
        function [pts] = getMultipleClicks(app)
            % Gets multiple clicks, before confirming with right click
            pan(app.ax,'off');
            zoom(app.ax,'off');
            
            app.clickPath = [];
            
            app.imgHandle.ButtonDownFcn = @(~,e) imClickAreaCallback(app,e);
            
            % Waits until ButtonDownFcn is set to empty again, indicating
            % that the callback was called and the clickPos modified
            waitfor(app.imgHandle, 'ButtonDownFcn', [])
            
            % In case it is aborted before the first click
            if isempty(app.clickPath)
                pts = [];
                return
            end
            pts = app.clickPath;
        end
        
        function [] = stopWaitingForUI(app)
            % In case the file is closed or the image frame changed,
            % stop waiting on clicks
            app.imgHandle.ButtonDownFcn = [];
            
        end
        
        
        function [] = removeClosestPoint(app, x, y)
            % Finds index of the points in cell points that is closest
            % to the input position, removes it.
            closestIx = 0;
            closestDist = 1000000000;
            
            pts = getCellPoints(app);
            rowi = int32(pts(:,1));
            coli = int32(pts(:,2));
            x = int32(x);
            y = int32(y);
            
            for i = 1:length(rowi)
                currDist = (rowi(i) - y)^2 + (coli(i) - x)^2;
                if currDist < closestDist
                    closestDist = currDist;
                    closestIx = i;
                end
            end
            
            newPts = pts(1:length(rowi) ~= closestIx, :);
            setCellPoints(app, newPts);
        end
    
        function [] = updatePosNbr(app)
            % Update field that specifies the position
            pos = app.posLookup(app.xyPos);
            app.PosNumber.Value = pos;
        end
        
        
        % ----------------------------------------------------------
        % STARTUP / LOADING FUNCTIONS
        % These functions are typically called only after loading of
        % a new file and are related to inializing the GUI.
        % ----------------------------------------------------------
        function [] = initializeSpinnerBounds(app)
            % Inizializes Spinners to have upper limit (or deactivate
            % if the upper limit is equal to the lower limit)
            sizeZ = app.reader.getSizeZ;
            sizeR = length(app.posLookup);
            sizeT = app.reader.getSizeT;
            sizeC = app.reader.getSizeC;
            
            function setUpperLimit(spinner, size)
                if size==1
                    spinner.Enable = 0;
                    spinner.Visible = 0;
                else
                    spinner.Limits = [1 size];
                end
            end
            setUpperLimit(app.ZSpinner, sizeZ)
            setUpperLimit(app.RegionSpinner, sizeR)
            setUpperLimit(app.TimeframeSpinner, sizeT)
            setUpperLimit(app.ChannelSpinner, sizeC)
            
            % Set currSeries for initial frame
            app.currSeries = app.posLookup(app.xyPos);
        end
        
        
        function [] = initializeParams(app, bf_ch, fluo_ch, is_bf)
            % Initializes parameters to their default values
            app.param.gen.fluo_ch = fluo_ch;
            app.param.gen.bf_ch = bf_ch;
            app.param.gen.is_bf = is_bf;
            
            % Watershed parameters
            ws.smooth = 2;
            ws.center_dilate = 2;
            app.param.ws = ws;
            
            % Get background parameters
            bg.dilate = 10;
            bg.smoothing = 5;
            app.param.bg = bg;
            
            % Points in cell parameters
            pic.erode=2;
            pic.dilate=4;
            pic.close=6;
            pic.blur=3;
            pic.distsmooth=2;
            app.param.pic=pic;
        end
        
        function [] = initializeAxis(app)
            % In case user has Matlab2018b or newer, replace UIAxes with
            % Axes to support drawing points and regions. 

            if verLessThan('matlab', '9.5')
                app.ax = app.UIAxes;
            else
                xlim = app.UIAxes.XLim;
                ylim = app.UIAxes.YLim;
                delete(app.UIAxes);
                    
                app.ax = axes(app.UIFigure);
                app.ax.XLim = xlim;
                app.ax.YLim = ylim;
                app.ax.Position = [.05, .35, .9, .6];
            end
        end
        
        end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgfile, savefile, rects, posLookup, bf_ch, fluo_ch, is_bf)
            
            [~, ~, ext] = fileparts(imgfile);
            
            switch ext
                % When loading existing file
                case '.mldatx'
                    app.res = matfile(imgfile, 'Writable', true);
                    loadMetadata(app)
                    app.savefilename = imgfile;
                    
                    % Create reader for image access
                    bfInitLogging('OFF');
                    app.reader = loci.formats.Memoizer(bfGetReader(), 0);
                    app.reader.setId(app.imgfile);
                    
                    % When creating new file
                case '.nd2'
                    initializeParams(app, bf_ch, fluo_ch, is_bf)
                    
                    app.imgfile = imgfile;
                    app.savefilename = savefile;
                    app.res = matfile(app.savefilename, 'Writable', true);
                    
                    % Create reader for image access
                    bfInitLogging('OFF');
                    app.reader = loci.formats.Memoizer(bfGetReader(), 0);
                    app.reader.setId(app.imgfile);
                    
                    % Calculate rectangles containing cells
                    app.rects = rects;
                    app.posLookup = posLookup;
                    
                    % Initialize empty result cells in output file
                    app.res.segMasks = cell(...
                        length(rects), ...
                        app.reader.getSizeZ, ...
                        app.reader.getSizeT);
                    app.res.cellFluo = cell(...
                        length(rects), ...
                        app.reader.getSizeZ, ...
                        app.reader.getSizeT, ...
                        app.reader.getSizeC);
                    app.res.cellArea = cell(...
                        length(rects), ...
                        app.reader.getSizeZ, ...
                        app.reader.getSizeT, ...
                        app.reader.getSizeC);

                    
                    % Initialize empty cellpoints
                    app.cellpoints = cell(...
                        length(rects), ...
                        app.reader.getSizeZ, ...
                        app.reader.getSizeT);
                    
                    % Save metadata
                    saveMetadata(app)
            end
            
            % Update App Positions
            initializeSpinnerBounds(app)
            initializeAxis(app)
            updatePos(app)
            updateVignette(app) 
            loadImageToMemory(app) 
            updateBGMask(app)
            findDtr(app)
            displayImg(app)
            updatePosNbr(app)
            % anticipate(app)
        end

        % Value changed function: ChannelSpinner, RegionSpinner, 
        % ZSpinner
        function doChangeSpinner(app, event)
            disableAll(app)
            say(app, "Processing")
            pause(0.05)
            stopWaitingForUI(app)
            updatePos(app)
            loadImageToMemory(app)
            updateVignette(app)
            updateBGMask(app)
            findDtr(app)
            displayImg(app)
            updateCellFluo(app)
            updatePosNbr(app)
            % anticipate(app)
            say(app, "Idle")
            activateAll(app)
        end

        % Button pushed function: AddPointButton
        function doAddPoint(app, event)
            disableAll(app)
            say(app,"Left-click to add points, right-click to stop");
            while 1
                [x, y] = getClickPoint(app);
                if isempty(x)
                    activateAll(app)
                    say(app, "Idle")
                    return % Click aborted
                end
                pts = getCellPoints(app);
                rowi = pts(:,1);
                coli = pts(:,2);
                newpts = [[rowi' y]', [coli' x]'];
                setCellPoints(app, newpts);
                displayImg(app);
            end            
        end

        % Button pushed function: RemovePointButton
        function doRemovePoint(app, event)
            disableAll(app)
            say(app, "Left-click to remove closest point, right-click to stop")
            while 1
                [x, y] = getClickPoint(app);
                if isempty(x)
                    activateAll(app)
                    say(app, "Idle")

                    return % Click aborted
                end
                removeClosestPoint(app, x, y);
                displayImg(app);
            end
        end

        % Close request function: UIFigure
        function doClose(app, event)
            updateCellFluo(app)
            stopWaitingForUI(app)
            app.reader.close();
            saveMetadata(app)
            delete(app)
        end

        % Button pushed function: SegmentButton
        function doSegment(app, event)
            % Callback function for Segmentation
            % Note: To further improve performance, improve cellWatershed or
            % correctSegMask
            
            % Warn if already segmask present
            if ~isempty(app.currmask)
                msg = 'Segmenting again will lead to loss of current segmentation mask.';
                title = 'Confirm Segmentation';
                selection = uiconfirm(app.UIFigure, msg, title,...
                    'Options',{'OK','Cancel'},...
                    'DefaultOption',2,'CancelOption',2);
                if strcmp(selection, 'Cancel')
                    return
                end
            end
            disableAll(app)
            say(app, "Processing")
            pause(.05)
            bfImg = app.currbf;
            if app.param.gen.is_bf % Invert if bf image
                bfImg = imcomplement(bfImg);
            end
            cellcenters = getCellPoints(app);
            seg = cellWatershedGUI3(bfImg, app.currbgmask, ...
                app.currdtr, cellcenters(:,1), ...
                cellcenters(:,2), app.param.ws);
            corrMask = correctSegMask(app, seg);
            fatBorder = fixBorderWidth(corrMask, app.BorderWidthSpinner.Value);
            setSegMask(app, fatBorder)
            displayImg(app);
            say(app, "Idle")
            activateAll(app)
        end

        % Button pushed function: AddtoRegionButton
        function doModifyRegion2(app, event)
            disableAll(app)
            while 1
            % Get region to modify 
                say(app,"Left-click to select region, right-click to stop");
                
                [x, y] = getClickPoint(app);
                if isempty(x)
                    activateAll(app)
                    say(app, "Idle")
                    return % Click aborted
                end
                
                currMask = app.currmask;
                if isempty(currMask)
                    activateAll(app)
                    say(app, "Idle")
                    return
                end
                regionNb = currMask(y, x);
                
                %say(app, "Click and hold to draw, right-click to stop")
                say(app, "Click to add vertex to polygon, Esc to cancel, double click to confirm")
                polyMask = getClickArea(app);
                if isempty(polyMask)
                    activateAll(app)
                    say(app, "Idle")
                    return % Click area aborted
                end
                
                say(app, "Processing");
                t1 = cputime;
                currMask(polyMask) = regionNb;
                t2 = cputime;
                setSegMask(app, currMask)
                t3 = cputime;
                displayImg(app)
                t4 = cputime;
                % updateCellFluo(app)
                e = cputime;
                say(app, "Idle")

%                 disp("TIMING MODIFY REGION")
%                 disp("Change Mask")
%                 disp(t2-t1)
%                 disp("Save Mask")
%                 disp(t3-t2)
%                 disp("Display Img")
%                 disp(t4-t3)
%                 disp("Update Fluorescence")
%                 disp(e-t4)
            end
        end

        % Button pushed function: MergeRegionsButton
        function doMerge(app, event)
            currMask = app.currmask;
            if isempty(currMask)
                return
            end
            
            disableAll(app)
            say(app, "Select base region, right-click to abort")
            % Get region to merge to
            [x1, y1] = getClickPoint(app);
            if isempty(x1)
                say(app, "Idle")
                activateAll(app)
                return % Click aborted
            end
            regionNb1 = currMask(y1, x1);
            
            % Get all other regions to merge
            say(app, "Select regions to reassign, right-click to confirm")
            pts = getMultipleClicks(app);
            if isempty(pts)
                say(app, "Idle")
                activateAll(app)
                return
            end
            say(app, "Processing")
            for i=1:size(pts,1)
                y2 = uint32(pts(i,1));
                x2 = uint32(pts(i,2));
                regionNb2 = currMask(x2, y2);
                currMask(currMask == regionNb2) = regionNb1;
            end
            
            % Correct numbers to corresponding cells
            setSegMask(app, currMask)
            displayImg(app);
            say(app, "Idle")
            activateAll(app)
            % updateCellFluo(app)
        end

        % Value changed function: ShowCellNumbersCheckBox
        function doShowCellNumbers(app, event)
            say(app, "Processing")
            disableAll(app)
            displayImg(app)
            activateAll(app)
            say(app, "Idle")
        end

        % Button pushed function: RedoLabelsButton
        function doRedoLabels(app, event)
            currMask = app.currmask;
            if isempty(currMask)
                return
            end
            
            % Ask user whether he's sure
            msg = 'Redoing labels will lead to loss of current labels.';
            title = 'Confirm Redoing Labels';
            selection = uiconfirm(app.UIFigure, msg, title,...
                'Options',{'OK','Cancel'},...
                'DefaultOption',2,'CancelOption',2);
            if strcmp(selection, 'Cancel')
                return
            end
            
            disableAll(app)
            say(app, "Processing")
            pause(.05)
            corrected = correctSegMask(app, currMask);
            
            % Correct numbers to corresponding cells
            setSegMask(app, corrected)
            displayImg(app);
            % updateCellFluo(app)
            activateAll(app)
            say(app, "Idle")
            
        end

        % Button pushed function: ChangeLabelButton
        function doChangeLabel(app, event)
            say(app, "Click to select region, right-click to abort")
            disableAll(app)
            [x, y] = getClickPoint(app);
            if isempty(x)
                say(app, "Idle")
                activateAll(app)
                return % Click aborted
            end
            input = inputdlg('Enter new cell number');
            newNb = str2num(input{1});
            if isempty(newNb)
                activateAll(app)
                say(app, "Idle")
                return
            end
            say(app, "Processing")
            currMask = app.currmask;
            regionNb = currMask(y, x);
            
            currMask(currMask == regionNb) = newNb;
            setSegMask(app, currMask);
            displayImg(app);
            % updateCellFluo(app)
            updatePosNbr(app)
            activateAll(app)
            say(app, "Idle")
        end

        % Button pushed function: NewCellinAreaButton
        function doSplitCell2(app, event)
            disableAll(app)
            while 1
                say(app, "Select area, right-click to abort")
                [x, y] = getClickPoint(app);
                if isempty(x)
                    activateAll(app)
                    say(app, "Idle")
                    return % Click aborted
                end
                
                currMask = app.currmask;
                regionNb = currMask(y, x);
                
                say(app, "Draw area, right-click to abort")
                polyMask = getClickArea(app);
                if isempty(polyMask)
                    activateAll(app)
                    say(app, "Idle")
                    return % Click area aborted
                end
                
                say(app, "Processing")
                toEdit = currMask == regionNb & polyMask;
                            
                currMask(toEdit) = max(currMask(:)) + 1;
                setSegMask(app, currMask);
                displayImg(app);
                say(app, "Idle")
                % updateCellFluo(app)
            end
        end

        % Button pushed function: PanButton
        function doPan(app, event)
            pan(app.ax,'on');
        end

        % Button pushed function: ZoomButton
        function doZoom(app, event)
            zoom(app.ax,'on');
        end

        % Button pushed function: PlotCurrentChannelButton
        function doPlot3(app, event)
            say(app, "Processing")
            disableAll(app)
            pause(.05)
            updateCellFluo(app)
            
            r = app.xyPos;
            z = app.zPos;
            c = app.cPos;
            savefile = app.savefilename;
            area = extractArea(savefile, r, z, c);
            fluo = extractFluorescence(savefile, r, z, c);
            avg = fluo./area;
            f = figure;
            plot(avg')
            xl = xlim();
            
            [ncell, ~] = size(avg);
            
            for cellix=1:ncell
                text(xl(1)+0.05, avg(cellix, 1), num2str(cellix))
            end
            activateAll(app)
            say(app, "Done")
        end

        % Value changed function: ShowPointsCheckBox
        function doClickShowPoints(app, event)
            say(app, "Processing")
            disableAll(app)
            displayImg(app)
            activateAll(app)
            say(app, "Idle")
        end

        % Value changed function: ShowSegMask
        function doShowSegMask(app, event)
            say(app, "Processing")
            disableAll(app)
            displayImg(app)
            activateAll(app)
            say(app, "Idle")
        end

        % Button pushed function: RedetectButton
        function doRedetectPoints(app, event)
            % Warn if already segmask present
            if ~isempty(getCellPoints(app))
                msg = 'Redetecting will lead to loss of current points.';
                title = 'Confirm Redetection';
                selection = uiconfirm(app.UIFigure, msg, title,...
                    'Options',{'OK','Cancel'},...
                    'DefaultOption',2,'CancelOption',2);
                if strcmp(selection, 'Cancel')
                    return
                end
            end
            say(app, "Processing")
            disableAll(app)
            findCellCenter(app)
            displayImg(app)
            activateAll(app)
            say(app, "Idle")
        end

        % Value changed function: TimeframeSpinner
        function doChangeTime(app, event)
            stopWaitingForUI(app)
            disableAll(app)
            pause(0.05)
            
            say(app, "Processing")
            updatePos(app)
            
            t1 = cputime;
            
            % If going to next or previous frame, use anticipated
            % data
            % Source of bug?
            % If uncommenting, uncomment all events of anticipate
%             if event.Value == event.PreviousValue+1
%                 loadNextToMemory(app)
%             elseif event.Value == event.PreviousValue-1
%                 loadPreviousToMemory(app)
%             else
%                 loadImageToMemory(app)
%             end
            
            loadImageToMemory(app)
            
            t2 = cputime;
            updateBGMask(app)
            t3 = cputime;
            findDtr(app)
            t4 = cputime;
            displayImg(app)
            t5 = cputime;
            updateCellFluo(app)
            t6 = cputime;
            updatePosNbr(app)
            t7 = cputime;
            % anticipate(app)
            t8 = cputime;
            
            activateAll(app)
            say(app, "Idle")
            
%             disp("Load to memory")
%             disp(t2-t1)
%             disp("Update BG Mask")
%             disp(t3-t2)
%             disp("Find Dtr")
%             disp(t4-t3)
%             disp("Display")
%             disp(t5-t4)
%             disp("Update Fluo")
%             disp(t6-t5)
%             disp("Update Pos Nbr")
%             disp(t7-t6)
%             disp("Anticipate")
%             disp(t8-t7)
        end

        % Button pushed function: ExportCurrentChannelButton
        function doExport(app, event)
            say(app, "Processing")
            disableAll(app)
            pause(.05)
            updateCellFluo(app)
            
            r = app.xyPos;
            z = app.zPos;
            c = app.cPos;
            savefile = app.savefilename;
            area = extractArea(savefile, r, z, c);
            fluo = extractFluorescence(savefile, r, z, c);
            avg = fluo./area;
            
            tab = array2table(avg);
            
            nt = size(avg,2);
            varnames = cell(nt, 1);
            for i=1:nt
                varnames{i} = strcat('Time ', num2str(i));
            end
            tab.Properties.VariableNames = varnames;
            
            nc = size(avg,1);
            cellnames = cell(nc, 1);
            for i=1:nc
                cellnames{i} = strcat('Cell ', num2str(i));
            end
            tab.Properties.RowNames = cellnames;
            
            [file, path]  = uiputfile({'*.csv';'*.txt';'*.*'}, ...
                "Select File for Saving Results");
            
            writetable(tab, fullfile(path, file), 'WriteRowNames',true);
            activateAll(app)
            say(app, "Idle")
        end

        % Button pushed function: RedrawCellButton_2
        function doRedraw(app, event)
            disableAll(app)
            % Get cell to redraw
            say(app, "Select region, right-click to abort (small waiting time)")
            [x, y] = getClickPoint(app);
            if isempty(x)
                activateAll(app)
                say(app, "Idle")
                return % Click aborted
            end
            
            currMask = app.currmask;
            if isempty(currMask)
                activateAll(app)
                say(app, "Idle")
                return
            end
            regionNb = currMask(y, x);
            
            say(app, "Draw region, right-click to abort (small waiting time)")
            polyMask = getClickArea(app);
            if isempty(polyMask)
                activateAll(app)
                say(app, "Idle")
                return % Click area aborted
            end
            
            say(app, "Processing")
            currMask(currMask == regionNb) = 0;
            currMask(polyMask) = regionNb;
            
            setSegMask(app, currMask)
            displayImg(app)
            say(app, "Idle")
            activateAll(app)
            % updateCellFluo(app)

        end

        % Value changed function: BorderWidthSpinner
        function doChangeWidth(app, event)
            % Ensure that value is odd
            value = app.BorderWidthSpinner.Value;
            if mod(value, 2)==0
                app.BorderWidthSpinner.Value = value+1;
            end
        end

        % Button pushed function: SnakeonCellButton
        function doCellSnake(app, event)
            disableAll(app)
            while 1
            % Get cell to redraw
                [x, y] = getClickPoint(app);
                if isempty(x)
                    activateAll(app)
                    return % Click aborted
                end
                
                currMask = app.currmask;
                if isempty(currMask)
                    activateAll(app)
                    return
                end
                regionNb = currMask(y, x);
                
                if regionNb<1
                    continue % Clicked on bg
                end
                
                newMask = cellSnake(app.currbf, currMask, regionNb); 
                
                setSegMask(app, newMask)
                displayImg(app)
            end

        end

        % Button pushed function: BrushDrawButton
        function doBrush(app, event)
            say(app, "Select region, right-click to abort (small waiting time)")
            disableAll(app)
            [x, y] = getClickPoint(app);
            if isempty(x)
                activateAll(app)
                say(app, "Idle")
                return % Click aborted
            end
            
            currMask = app.currmask;
            if isempty(currMask)
                activateAll(app)
                say(app, "Idle")
                return
            end
            regionNb = currMask(y, x);
            
            while 1          
                say(app, "Click and hold to draw, single click to stop")
                lw = app.BrushSizeSpinner.Value;
                mask = freehandDraw(app, lw);
                if isempty(mask)
                    say(app, "Idle")
                    activateAll(app)
                    return
                end
                
                say(app, "Processing")
                currMask(mask) = regionNb;
                setSegMask(app, currMask)
                displayImg(app)
                say(app, "Idle")
            end

        end

        % Key press function: UIFigure
        function shortcut(app, event)
            key = event.Key;
            e = [];
            switch key
                case 's'
                    doSegment(app, e)
                case 'a'
                    doAddPoint(app, e)
                case 'r'
                    doRemovePoint(app, e)
                case 'm'
                    doMergeRegions(app, e)
                case 'b'
                    doBrush(app, e)
                case 'z'
                    doZoom(app, e)
                case 'p'
                    doPan(app, e)
                case 'l'
                    doChangeLabel(app, e)
                case 'uparrow'
                    e.PreviousValue = app.TimeframeSpinner.Value;
                    e.Value = e.PreviousValue + 1;
                    app.TimeframeSpinner.Value = e.Value;
                    doChangeTime(app, e)
                case 'downarrow'
                    e.PreviousValue = app.TimeframeSpinner.Value;
                    e.Value = e.PreviousValue - 1;
                    app.TimeframeSpinner.Value = e.Value;
                    doChangeTime(app, e)
                case 'd'
                    doRedraw(app, e)
                case 'n'
                    doSplitCell2(app, e)
                case 't'
                    doModifyRegion2(app, e)
                case 'f'
                    app.ShowSegMask.Value = ~app.ShowSegMask.Value;
                    doShowSegMask(app,e)
                    
                otherwise
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @doClose, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @shortcut, true);
            app.PointDetectionPanel = uipanel(app.UIFigure);
            app.PointDetectionPanel.Title = 'Point Detection';
            app.AddPointButton = uibutton(app.PointDetectionPanel, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @doAddPoint, true);
            app.AddPointButton.Text = 'Add Point';
            app.RemovePointButton = uibutton(app.PointDetectionPanel, 'push');
            app.RemovePointButton.ButtonPushedFcn = createCallbackFcn(app, @doRemovePoint, true);
            app.RemovePointButton.Text = 'Remove Point';
            app.RedetectButton = uibutton(app.PointDetectionPanel, 'push');
            app.RedetectButton.ButtonPushedFcn = createCallbackFcn(app, @doRedetectPoints, true);
            app.RedetectButton.Text = 'Redetect';
            app.SegmentationPanel = uipanel(app.UIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.AddtoRegionButton = uibutton(app.SegmentationPanel, 'push');
            app.AddtoRegionButton.ButtonPushedFcn = createCallbackFcn(app, @doModifyRegion2, true);
            app.AddtoRegionButton.Text = 'Add to Region';
            app.SegmentButton = uibutton(app.SegmentationPanel, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @doSegment, true);
            app.SegmentButton.Text = 'Segment';
            app.MergeRegionsButton = uibutton(app.SegmentationPanel, 'push');
            app.MergeRegionsButton.ButtonPushedFcn = createCallbackFcn(app, @doMerge, true);
            app.MergeRegionsButton.Text = 'Merge Regions';
            app.NewCellinAreaButton = uibutton(app.SegmentationPanel, 'push');
            app.NewCellinAreaButton.ButtonPushedFcn = createCallbackFcn(app, @doSplitCell2, true);
            app.NewCellinAreaButton.Text = 'New Cell in Area';
            app.RedrawCellButton_2 = uibutton(app.SegmentationPanel, 'push');
            app.RedrawCellButton_2.ButtonPushedFcn = createCallbackFcn(app, @doRedraw, true);
            app.RedrawCellButton_2.Text = 'Redraw Cell';
            app.BorderWidthSpinnerLabel = uilabel(app.SegmentationPanel);
            app.BorderWidthSpinnerLabel.Text = 'Border Width';
            app.BorderWidthSpinner = uispinner(app.SegmentationPanel);
            app.BorderWidthSpinner.Step = 2;
            app.BorderWidthSpinner.Limits = [1 Inf];
            app.BorderWidthSpinner.RoundFractionalValues = 'on';
            app.BorderWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeWidth, true);
            app.BorderWidthSpinner.Value = 1;
            app.FrameSelectionPanel = uipanel(app.UIFigure);
            app.FrameSelectionPanel.Title = 'Frame Selection';
            app.TimeframeSpinnerLabel = uilabel(app.FrameSelectionPanel);
            app.TimeframeSpinnerLabel.Text = 'Timeframe';
            app.TimeframeSpinner = uispinner(app.FrameSelectionPanel);
            app.TimeframeSpinner.Limits = [1 Inf];
            app.TimeframeSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeTime, true);
            app.TimeframeSpinner.Value = 1;
            app.RegionSpinnerLabel = uilabel(app.FrameSelectionPanel);
            app.RegionSpinnerLabel.Text = 'Region';
            app.RegionSpinner = uispinner(app.FrameSelectionPanel);
            app.RegionSpinner.Limits = [1 Inf];
            app.RegionSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeSpinner, true);
            app.RegionSpinner.Value = 1;
            app.ChannelSpinnerLabel = uilabel(app.FrameSelectionPanel);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.FrameSelectionPanel);
            app.ChannelSpinner.Limits = [1 Inf];
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeSpinner, true);
            app.ChannelSpinner.Value = 1;
            app.ZSpinnerLabel = uilabel(app.FrameSelectionPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.FrameSelectionPanel);
            app.ZSpinner.Limits = [1 Inf];
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeSpinner, true);
            app.ZSpinner.Value = 1;
            app.PositionNbrEditFieldLabel = uilabel(app.FrameSelectionPanel);
            app.PositionNbrEditFieldLabel.Text = 'Position Nbr';
            app.PosNumber = uieditfield(app.FrameSelectionPanel, 'numeric');
            app.PosNumber.Editable = 'off';
            app.PosNumber.Value = 1;
            app.LabelingPanel = uipanel(app.UIFigure);
            app.LabelingPanel.Title = 'Labeling';
            app.RedoLabelsButton = uibutton(app.LabelingPanel, 'push');
            app.RedoLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @doRedoLabels, true);
            app.RedoLabelsButton.Text = 'Redo Labels';
            app.ChangeLabelButton = uibutton(app.LabelingPanel, 'push');
            app.ChangeLabelButton.ButtonPushedFcn = createCallbackFcn(app, @doChangeLabel, true);
            app.ChangeLabelButton.Text = 'Change Label';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PanButton = uibutton(app.UIFigure, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @doPan, true);
            app.PanButton.Text = 'Pan';
            app.ZoomButton = uibutton(app.UIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @doZoom, true);
            app.ZoomButton.Text = 'Zoom';
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Title = 'Export';
            app.PlotCurrentChannelButton = uibutton(app.ExportPanel, 'push');
            app.PlotCurrentChannelButton.ButtonPushedFcn = createCallbackFcn(app, @doPlot3, true);
            app.PlotCurrentChannelButton.Text = 'Plot Current Channel ';
            app.ShowPointsCheckBox = uicheckbox(app.UIFigure);
            app.ShowPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @doClickShowPoints, true);
            app.ShowPointsCheckBox.Text = 'Show Points';
            app.ShowPointsCheckBox.Value = true;
            app.ShowSegMask = uicheckbox(app.UIFigure);
            app.ShowSegMask.ValueChangedFcn = createCallbackFcn(app, @doShowSegMask, true);
            app.ShowSegMask.Text = 'Show Seg Mask';
            app.ShowSegMask.Value = true;
            app.ExportCurrentChannelButton = uibutton(app.UIFigure, 'push');
            app.ExportCurrentChannelButton.ButtonPushedFcn = createCallbackFcn(app, @doExport, true);
            app.ExportCurrentChannelButton.Text = 'Export Current Channel';
            app.SnakesPanel = uipanel(app.UIFigure);
            app.SnakesPanel.Title = 'Snakes';
            app.SnakeonCellButton = uibutton(app.SnakesPanel, 'push');
            app.SnakeonCellButton.ButtonPushedFcn = createCallbackFcn(app, @doCellSnake, true);
            app.SnakeonCellButton.Enable = 'off';
            app.SnakeonCellButton.Text = 'Snake on Cell';
            app.ShowCellNumbersCheckBox = uicheckbox(app.UIFigure);
            app.ShowCellNumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @doShowCellNumbers, true);
            app.ShowCellNumbersCheckBox.Text = 'Show Cell Numbers';
            app.WhattodoLabel = uilabel(app.UIFigure);
            app.WhattodoLabel.Text = 'What to do';
            app.todo = uitextarea(app.UIFigure);
            app.todo.Editable = 'off';
            app.BrushDrawButton = uibutton(app.UIFigure, 'push');
            app.BrushDrawButton.ButtonPushedFcn = createCallbackFcn(app, @doBrush, true);
            app.BrushDrawButton.Text = 'Brush Draw';
            app.BrushSizeSpinnerLabel = uilabel(app.UIFigure);
            app.BrushSizeSpinnerLabel.Text = 'Brush Size';
            app.BrushSizeSpinner = uispinner(app.UIFigure);
            app.BrushSizeSpinner.Limits = [1 Inf];
            app.BrushSizeSpinner.Value = 5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = doSegComp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end