classdef startSeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SelectFileButton          matlab.ui.control.Button
        SelectSaveFileButton      matlab.ui.control.Button
        CONFIRMButton             matlab.ui.control.Button
        LightChannelSpinnerLabel  matlab.ui.control.Label
        LightChannelSpinner       matlab.ui.control.Spinner
        FLUOChannelSpinnerLabel   matlab.ui.control.Label
        FLUOChannelSpinner        matlab.ui.control.Spinner
        DisplayChannelsButton     matlab.ui.control.Button
        InvertCB                  matlab.ui.control.CheckBox
        IdentifyCellContainingRegionsPanel  matlab.ui.container.Panel
        AutomaticDetectionButton  matlab.ui.control.Button
        ManualDetectionButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        openfile % File to Open
        savefilename % File to save to
        
    end
    
    properties (Access = public)
        rects % Rectangles of pixels that are interesting
        posLookup % Lookup table from rectangle nr to position nr
    end
    
    methods (Access = private)
        function [] = noRects(app)
            % In case no rectangles are determined, neither manually 
            % nor automatically
            bfInitLogging('OFF');
            reader = loci.formats.Memoizer(bfGetReader(), 0);
            reader.setId(app.openfile);
            
            fullRect = [1, 1, reader.getSizeX, reader.getSizeY];
            nb_series = reader.getSeriesCount;
            tmp = cell(nb_series, 1);
            for i = 1:length(tmp)
                tmp{i} = fullRect;
            end
            app.rects = tmp;
            app.posLookup = 1:nb_series;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectSaveFileButton.Enable = 0;
            app.DisplayChannelsButton.Enable = 0;
            app.CONFIRMButton.Enable = 0;
            app.LightChannelSpinner.Enable = 0;
            app.FLUOChannelSpinner.Enable = 0;
            app.InvertCB.Enable = 0;
        end

        % Button pushed function: SelectFileButton
        function loadFile(app, event)
            [filename, path] = uigetfile('*.mldatx;*.nd2', 'Select file to load.');
            
            % If no file was loaded do nothing
            if filename == 0
                return
            end
            
            % File to open
            app.openfile = fullfile(path, filename);
            [~, ~, ext] = fileparts(app.openfile);
            
            switch ext
                case '.mldatx'
                    app.SelectSaveFileButton.Enable = 0;
                    confirm(app)
                    
                case '.nd2'
                    app.SelectSaveFileButton.Enable = 1;
                    app.DisplayChannelsButton.Enable = 1;
                    app.LightChannelSpinner.Enable = 1;
                    app.FLUOChannelSpinner.Enable = 1;
                    app.InvertCB.Enable = 1;
                    app.ManualDetectionButton.Enable = 1;
                    app.AutomaticDetectionButton.Enable = 1;
            end
        end

        % Button pushed function: SelectSaveFileButton
        function selectSaveFile(app, event)
            [file, path] = uiputfile("", "Select File for Saving Results");
            
            % If no file was loaded do nothing
            if file == 0
                return
            end
            
            app.savefilename = fullfile(path,file);
            app.CONFIRMButton.Enable = 1;
        end

        % Button pushed function: DisplayChannelsButton
        function displayChannels(app, event)
            % Show figure with all channels, numerated according to their ix
            bfInitLogging('OFF');
            reader = loci.formats.Memoizer(bfGetReader(), 0);
            reader.setId(app.openfile);
            
            for c=1:reader.getSizeC
                ix = reader.getIndex(0, c-1, reader.getSizeT - 1) + 1;
                img = bfGetPlane(reader, ix);
                img8bit = uint8(rescale(img, 0, 255));
                imgWText = insertText(img8bit, [5, 5], c, 'FontSize',100, 'TextColor', 'r');
                allframes{c} = [imgWText];
            end
            
            figure;
            montage(allframes)
        end

        % Button pushed function: CONFIRMButton
        function confirm(app, event)
            
            [~, ~, ext] = fileparts(app.openfile);
            
            switch ext
                case '.mldatx'
                    doSegComp(app.openfile)
                    
                case '.nd2'
                    if isempty(app.rects)
                        noRects(app);
                    end

                    doSegComp(app.openfile, app.savefilename, app.rects, app.posLookup, ...
                              app.LightChannelSpinner.Value, app.FLUOChannelSpinner.Value, ...
                              1-app.InvertCB.Value)
            end
            
            UIFigureCloseRequest(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: AutomaticDetectionButton
        function doAutomaticDetection(app, event)
            bfInitLogging('OFF');
            reader = loci.formats.Memoizer(bfGetReader(), 0);
            reader.setId(app.openfile);
            
            
            % Get rectangles which correspond to regions of interest
            nb_series = reader.getSeriesCount;
            tmp = cell(nb_series, 1);
            for i=1:nb_series
                tmp{i} = nd2PosCellRegions(...
                    app.openfile, i, app.FLUOChannelSpinner.Value, ...
                    0, 3);
            end
            
            % Create unique ID for every rectangle, create lookup table to identify in
            % which position the rectangle is
            rectsPerPos = zeros(length(tmp), 1);
            for i=1:length(tmp)
                rectsPerPos(i) = length(tmp{i});
            end
            app.posLookup = repelem(1:length(tmp), rectsPerPos);
            app.rects = vertcat(tmp{:});
        end

        % Button pushed function: ManualDetectionButton
        function doManualDetection(app, event)
            doExtractRectangles(app, app.openfile);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadFile, true);
            app.SelectFileButton.Text = 'Select File';
            app.SelectSaveFileButton = uibutton(app.UIFigure, 'push');
            app.SelectSaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectSaveFile, true);
            app.SelectSaveFileButton.Text = 'Select Save File';
            app.CONFIRMButton = uibutton(app.UIFigure, 'push');
            app.CONFIRMButton.ButtonPushedFcn = createCallbackFcn(app, @confirm, true);
            app.CONFIRMButton.Text = 'CONFIRM';
            app.LightChannelSpinnerLabel = uilabel(app.UIFigure);
            app.LightChannelSpinnerLabel.Text = 'Light Channel';
            app.LightChannelSpinner = uispinner(app.UIFigure);
            app.LightChannelSpinner.Limits = [1 Inf];
            app.LightChannelSpinner.Value = 1;
            app.FLUOChannelSpinnerLabel = uilabel(app.UIFigure);
            app.FLUOChannelSpinnerLabel.Text = 'FLUO Channel';
            app.FLUOChannelSpinner = uispinner(app.UIFigure);
            app.FLUOChannelSpinner.Limits = [1 Inf];
            app.FLUOChannelSpinner.Value = 1;
            app.DisplayChannelsButton = uibutton(app.UIFigure, 'push');
            app.DisplayChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @displayChannels, true);
            app.DisplayChannelsButton.Text = 'Display Channels';
            app.InvertCB = uicheckbox(app.UIFigure);
            app.InvertCB.Text = 'Invert for Phase Contrast';
            app.IdentifyCellContainingRegionsPanel = uipanel(app.UIFigure);
            app.IdentifyCellContainingRegionsPanel.Title = 'Identify Cell Containing Regions';
            app.AutomaticDetectionButton = uibutton(app.UIFigure, 'push');
            app.AutomaticDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @doAutomaticDetection, true);
            app.AutomaticDetectionButton.Enable = 'off';
            app.AutomaticDetectionButton.Text = 'Automatic Detection';
            app.ManualDetectionButton = uibutton(app.UIFigure, 'push');
            app.ManualDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @doManualDetection, true);
            app.ManualDetectionButton.Enable = 'off';
            app.ManualDetectionButton.Text = 'Manual Detection';
        end
    end

    methods (Access = public)

        % Construct app
        function app = startSeg

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end